classdef interactiveAppMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeamVisualization            matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CrossectionalbeamvisualizationTab  matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        Beamparameterinfoconsole     matlab.ui.control.TextArea
        CollectorAxes                matlab.ui.control.UIAxes
        YpeakAxes                    matlab.ui.control.UIAxes
        XpeakAxes                    matlab.ui.control.UIAxes
        SurfaceAxes                  matlab.ui.control.UIAxes
        ContourAxes                  matlab.ui.control.UIAxes
        UserpanelPanel               matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        RS232Panel                   matlab.ui.container.Panel
        StartCOMButton               matlab.ui.control.Button
        StartstopparamButton         matlab.ui.control.Button
        UpdateplotButton             matlab.ui.control.Button
        ComportNr                    matlab.ui.control.NumericEditField
        HTTPPanel                    matlab.ui.container.Panel
        StartHTTPButton              matlab.ui.control.Button
        StartstopparamButtonHTTP     matlab.ui.control.Button
        UpdateplotButtonHTTP         matlab.ui.control.Button
        HTTPportNr                   matlab.ui.control.NumericEditField
        TriggerdelayEditFieldLabel   matlab.ui.control.Label
        TriggerdelayEditField        matlab.ui.control.NumericEditField
        BeamthresholdEditFieldLabel  matlab.ui.control.Label
        BeamthresholdEditField       matlab.ui.control.NumericEditField
        convfactorILabel             matlab.ui.control.Label
        convfactorIEditField         matlab.ui.control.NumericEditField
        DCcompensationCheckBox       matlab.ui.control.CheckBox
        TemporalevolutionTab         matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        BeamPositionAxes             matlab.ui.control.UIAxes
        BeamIntensityAxes            matlab.ui.control.UIAxes
        BeamFWHMAxes                 matlab.ui.control.UIAxes
        BeamSkewnessAxes             matlab.ui.control.UIAxes
        StartStopButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        ComPort
        HTTPPort
        
        RequestParametersTimer
        RequestParametersTimerHTTP
        
        ComOrHTTP = false;
        
        startStop = false;
        startStopHTTP = false;
        
        plotting_temp = false;
        
        beam_location = zeros(6,1);
        
        circular_plot_index = 0;
        
        beam_location_temp= NaN(25,2);      
        beam_intensity_temp = NaN(25,1);
        beam_fwhm_temp = NaN(25,2);
        beam_skewness_temp = NaN(25,2);
        
    end
    
    % Functions that can be called from inside callbacks
    
    methods (Access = private)
        
        % ----------------------------------------------------------------------------
        % UPDATE THE BEAM PARAMETERS over serial (COM) interface
        % ----------------------------------------------------------------------------
        
        function update_paramsCOM(app, ~, ~, ~)
            
            % Send the parameter request command tot the hardware
            
            write(app.ComPort, 255, 'uint8');
            write(app.ComPort, 2, 'uint8');
            write(app.ComPort, 255, 'uint8');
            
            % Check the size indicator of the response packet
            
            z = read(app.ComPort,1,'uint16');
            if isempty(z)
               stop(app.RequestParametersTimer);
               app.startStop = false;
               app.Beamparameterinfoconsole.Value = "No valid response received, please reset the hardware, then press Start/stop param";
               return
            end
            
            % Extract beam parameter information from the packet
            
            app.beam_location(1:6) = read(app.ComPort,6,'uint16');
            beam_intensity = read(app.ComPort, 1, 'uint32');
            beam_fwhm = read(app.ComPort, 2, 'uint16');
            beam_skewness = read(app.ComPort, 2, 'int32')/10000;
            beam_location_variance = read(app.ComPort, 2, 'int32')/10000;
             
            % Pass the beam parameters to the handler function for display
            % in the parameter console
            
            handle_parameters(app, beam_intensity, beam_fwhm, beam_skewness, beam_location_variance);
                        
        end
        
        % ----------------------------------------------------------------------------
        % UPDATE THE BEAM PARAMETERS over HTTP interface
        % ----------------------------------------------------------------------------
        
        function update_params_HTTP(app, ~, ~, ~)
            
            % Send the HTTP GET request to obtain parameter info from the
            % daemon
            
            header = [];
            retrieve_parameters = matlab.net.http.RequestMessage(matlab.net.http.RequestMethod.GET, header);
            [response, ~, ~] = send(retrieve_parameters, strcat('http://localhost:', app.HTTPportNr.Value, '/api/latest'));
           
            % Extract beam parameter information from the response packet
            
            data = response.Body.Data;
            
            app.beam_location(1) = data.peak_x_max;
            app.beam_location(2) = data.peak_x_start;
            app.beam_location(3) = data.peak_x_end;
            app.beam_location(4) = data.peak_y_max;
            app.beam_location(5) = data.peak_y_start;
            app.beam_location(6) = data.peak_y_end;
            
            beam_intensity = data.beam_intensity;
            
            beam_fwhm(1) = data.fwhm_x;
            beam_fwhm(2) = data.fwhm_y;
            
            beam_skewness(1) = str2double(data.skewness_x);
            beam_skewness(2) = str2double(data.skewness_y);
            
            beam_position_variance(1) = str2double(data.peak_x_variance);
            beam_position_variance(2) = str2double(data.peak_y_variance);
            
            % Pass the beam parameters to the handler function for display
            % in the parameter console
            
            handle_parameters(app, beam_intensity, beam_fwhm, beam_skewness, beam_position_variance);
            
        end
        
        %----------------------------------------------------------------------
        % PLOT THE BEAM PARAMETERS OVER TIME
        % ---------------------------------------------------------------------
        
        function plot_time_evolution_params(app)
            
              % Plot the temporal evolution of each beam parameter in the
              % second tab of the application window
                
                plot(app.BeamPositionAxes, app.beam_location_temp(:,1), '-ok', 'Color', 'blue');
                hold(app.BeamPositionAxes, 'on');
                plot(app.BeamPositionAxes, app.beam_location_temp(:,2), '-ok', 'Color', 'red');
                axis(app.BeamPositionAxes,[0 25 0 8334]);
                legend(app.BeamPositionAxes,'X peak', 'Y peak');
                hold(app.BeamPositionAxes, 'off');
                
                
                plot(app.BeamIntensityAxes, app.beam_intensity_temp(:,1), '-ok', 'Color', 'black');
                axis(app.BeamIntensityAxes,[0 25 0 100000]);
                
                plot(app.BeamFWHMAxes, app.beam_fwhm_temp(:,1), '-ok', 'Color', 'blue');
                hold(app.BeamFWHMAxes, 'on');
                plot(app.BeamFWHMAxes, app.beam_fwhm_temp(:,2), '-ok', 'Color', 'red');
                axis(app.BeamFWHMAxes,[0 25 0 500]);
                legend(app.BeamFWHMAxes,'X peak', 'Y peak');
                hold(app.BeamFWHMAxes, 'off');
                
                plot(app.BeamSkewnessAxes, app.beam_skewness_temp(:,1), '-ok', 'Color', 'blue');
                hold(app.BeamSkewnessAxes, 'on');
                plot(app.BeamSkewnessAxes, app.beam_skewness_temp(:,2), '-ok', 'Color', 'red');
                axis(app.BeamSkewnessAxes,[0 25 -3 3]);
                legend(app.BeamSkewnessAxes,'X peak', 'Y peak');
                hold(app.BeamSkewnessAxes, 'off');
        end
        
        % ------------------------------------------------------------------------------
        % DISPLAY PARAMETERS ON THE DASHBOARD INSANTANEOUSLY
        % ------------------------------------------------------------------------------
        
        function handle_parameters(app, beam_intensity, beam_fwhm, beam_skewness, beam_location_variance)
            
       beam_str = {[''],...
                ['  COMPUTED BEAM PARAMETERS '],['********************************************************************'] , ...
                [''],...
                ['                                                      X crossection                     Y crossection'],...
                [''],...
                ['  Peak left edge   :                             ' num2str(round(app.beam_location(2)/69.45,2)) ' mm                           ' num2str(round((app.beam_location(5)-4167)/69.45,2)) ' mm'] , ...
                [''],...
                ['  Peak centre      :                              ' num2str(round(app.beam_location(1)/69.45,2)) ' mm                           ' num2str(round((app.beam_location(4)-4167)/69.45,2)) ' mm'], ...
                [''],...
                ['  Peak right edge      :                        ' num2str(round(app.beam_location(3)/69.45,2)) ' mm                           ' num2str(round((app.beam_location(6)-4167)/69.45,2)) ' mm'], ...
                [''],...
                ['  Peak position variance      :            ' num2str(beam_location_variance(1)/69.45) '                            ' num2str(beam_location_variance(2)/69.45)], ...
                [''],...
                ['  Beam intensity      :                                                  ' num2str(round(beam_intensity/app.convfactorIEditField.Value,2)) ' nA                        ' ], ...
                [''],...
                ['  Beam FWHM        :                           '  num2str(round(beam_fwhm(1)/69.45,2)) ' mm                               ' num2str(round(beam_fwhm(2)/69.45,2)) ' mm'], ...
                [''],...
                ['  Beam skewness        :                     '  num2str(beam_skewness(1)) '                                ' num2str(beam_skewness(2)) ]};
            
            app.Beamparameterinfoconsole.Value = beam_str;
            
            if app.circular_plot_index < 25
                app.circular_plot_index = app.circular_plot_index+1;
            end
            
            app.beam_intensity_temp(app.circular_plot_index) = beam_intensity;
            app.beam_fwhm_temp(app.circular_plot_index,:) = beam_fwhm;
            app.beam_skewness_temp(app.circular_plot_index,:) = beam_skewness;
            app.beam_location_temp(app.circular_plot_index,1) = app.beam_location(1);
            app.beam_location_temp(app.circular_plot_index,2) = app.beam_location(4);
            
            
            if app.plotting_temp
                
               plot_time_evolution_params(app);
                
               if app.circular_plot_index == 25
                    app.beam_location_temp = circshift(app.beam_location_temp,-1,1);
                    app.beam_intensity_temp =circshift(app.beam_intensity_temp,-1,1);
                    app.beam_fwhm_temp = circshift(app.beam_fwhm_temp,-1,1);
                    app.beam_skewness_temp = circshift(app.beam_skewness_temp,-1,1);
                end
            
            end
            
        end
        
        
        %------------------------------------------------------------------------------
        % PLOT THE COLLECTOR SIGNAL IN A SCOPE SWEEP, PEAK ZOOMS AND
        % SURFACE/CONTOUR PLOTS
        % ------------------------------------------------------------------------------
        
        function plot_collector_signal(app, plot_data)
            
             plot(app.CollectorAxes, plot_data);
            
            % Compute and plot peak1 (X-crossection)
            
            peak1 = plot_data(app.beam_location(2): app.beam_location(3))*3.3/4096;
            bar(app.XpeakAxes, peak1);
            
            % Compute and plot peak2 (Y-crossection)
            
            peak2 = plot_data(app.beam_location(5): app.beam_location(6))*3.3/4096;
            bar(app.YpeakAxes, peak2);
            
            % Create the surface plot in the second tab
            peak_width_1 =  app.beam_location(3)- app.beam_location(2);
            peak_width_2 = app.beam_location(6)- app.beam_location(5);
            
            grid = zeros(peak_width_1,peak_width_2);
            [X,Y] = meshgrid(1:1:peak_width_2,1:1:peak_width_1);
            for i = 1:peak_width_1
                for j = 1:peak_width_2
                    grid(i,j) = (peak1(i)/sum(peak1))*(peak2(j)/sum(peak2));
                end
            end
            surf(app.SurfaceAxes,X,Y,grid, 'edgecolor', 'none');
            
             % Create the contour plot in the second tab
    
             grid = zeros(peak_width_1,peak_width_2);
             [X,Y] = meshgrid(1:1:peak_width_2,1:1:peak_width_1);
             for i = 1:peak_width_1
                 for j = 1:peak_width_2
                     grid(i,j) = (peak1(i)/sum(peak1))*(peak2(j)/sum(peak2));
                 end
             end
             
             contour(app.ContourAxes,X,Y,grid);
             
        end
        
        function update_plot_HTTP(app, ~, ~, ~)
         
            header = [];
            retrieve_plot_data = matlab.net.http.RequestMessage(matlab.net.http.RequestMethod.GET, header);
            [response, ~, ~] = send(retrieve_plot_data, strcat('http://localhost:', app.HTTPportNr.Value, '/api/latest'));
            
            plotdata = str2double(split(convertCharsToStrings(response.Body.Data.data), ';'));
            plot_collector_signal(app, plotdata);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartCOMButton
        function StartCOMButtonPushed(app, event)
            try
                app.ComPort = serialport(strcat('COM', num2str(app.ComportNr.Value)), 115200, 'Timeout', app.ComportNr.Value);
                app.Beamparameterinfoconsole.Value = 'Connected to board via COMport';
            catch
                app.Beamparameterinfoconsole.Value = 'Could not connect to the specified COM port';
            end
            
            app.ComOrHTTP = false;
        end

        % Button pushed function: StartstopparamButton
        function StartstopparamButtonPushed(app, event)
            app.startStop = ~app.startStop;
            
            if app.startStop == true
                app.RequestParametersTimer = timer('StartDelay', 1, 'Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
                app.RequestParametersTimer.TimerFcn = {@app.update_paramsCOM, 'start message'};
                start(app.RequestParametersTimer);
            else
                stop(app.RequestParametersTimer);
                
            end
         
        end

        % Button pushed function: UpdateplotButton
        function UpdateplotButtonPushed(app, event)
            
            stop(app.RequestParametersTimer);
            
            % Send the command to the microcontroller for getting plot data
    
            write(app.ComPort, 255, 'uint8');
            write(app.ComPort, 3, 'uint8');
            write(app.ComPort, 255, 'uint8');
            
            try
                read(app.ComPort, 1 ,'uint16')
                plot_data = read(app.ComPort,8334,'uint16');
                plot_collector_signal(app, plot_data);
            catch
                app.Beamparameterinfoconsole.Value = "Failed reading plor data, press start/stop";
            end
            
            start(app.RequestParametersTimer);
            
        end

        % Close request function: BeamVisualization
        function BeamVisualizationCloseRequest(app, event)
            if app.ComOrHTTP == false
                stop(app.RequestParametersTimer);
            else
                stop(app.RequestParametersTimerHTTP);
            end
            delete(app.RequestParametersTimer);
            delete(app)
            
        end

        % Value changed function: TriggerdelayEditField
        function AdjustTriggerDelay(app, event)
            newvalue = app.TriggerdelayEditField.Value;
            
            if app.ComOrHTTP == false
                write(app.ComPort, 255, 'uint8')
                write(app.ComPort, 0, 'uint8');
                write(app.ComPort, uint8(newvalue), 'uint8');
                read(app.ComPort, 5, 'uint8');
            else
                s = struct('request_id', "1", 'set_trigger_delay', newvalue);
                body = matlab.net.http.MessageBody(jsonencode(s));
                header = [];
                set_trigger_delay = matlab.net.http.RequestMessage(matlab.net.http.RequestMethod.POST, header, body);
                [response, ~, ~] = send(set_trigger_delay, strcat('http://localhost:', app.HTTPportNr.Value, '/api/latest'));
            end
            
                        
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            app.plotting_temp = ~app.plotting_temp;
        end

        % Button pushed function: StartHTTPButton
        function HTTPstart(app, event)
            s = struct('request_id', "1", 'get_beam_params', true);
            body = matlab.net.http.MessageBody(jsonencode(s));
            header = [];
            ask_for_parameters = matlab.net.http.RequestMessage(matlab.net.http.RequestMethod.POST, header, body);
            [response, completedrequest, history] = send(ask_for_parameters, strcat('http://localhost:', app.HTTPportNr.Value, '/api/latest'));
            app.ComOrHTTP = true;
        end

        % Button pushed function: StartstopparamButtonHTTP
        function HTTPStartStop(app, event)
            app.startStopHTTP = ~app.startStopHTTP;
            
            if  app.startStopHTTP == true
                app.RequestParametersTimerHTTP = timer('StartDelay', 1, 'Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
                app.RequestParametersTimerHTTP.TimerFcn = {@app.update_params_HTTP,'start message'};
                start(app.RequestParametersTimerHTTP);
            else
                stop(app.RequestParametersTimerHTTP);
                
            end
         
        end

        % Button pushed function: UpdateplotButtonHTTP
        function UpdateplotHTTPButtonPushed(app, event)
            
            stop(app.RequestParametersTimerHTTP);
            
            s = struct('request_id', "1", 'get_plot_data', true);
            body = matlab.net.http.MessageBody(jsonencode(s));
            header = [];
            
            ask_for_plotdata = matlab.net.http.RequestMessage(matlab.net.http.RequestMethod.POST, header, body);
            [response, completedrequest, history] = send(ask_for_plotdata, strcat('http://localhost:', app.HTTPportNr.Value, '/api/latest'));

            RequestPlotTimerHTTP = timer('StartDelay', 3, 'TasksToExecute', 1, 'ExecutionMode', 'singleShot');
            RequestPlotTimerHTTP.TimerFcn = {@app.update_plot_HTTP,'start message'};
            
            start(RequestPlotTimerHTTP);
            start(app.RequestParametersTimerHTTP);
        end

        % Value changed function: DCcompensationCheckBox
        function dc_offset_compensation(app, event)
            newvalue = app.DCcompensationCheckBox.Value;
            if newvalue ~= 0
               newvalue = 255;
            end
            
            write(app.ComPort, 255, 'uint8')
            write(app.ComPort, 4, 'uint8');
            write(app.ComPort, uint8(newvalue), 'uint8');
            read(app.ComPort, 5, 'uint8');
            
        end

        % Value changed function: BeamthresholdEditField
        function adjustbeamThreshold(app, event)
            newvalue = app.BeamthresholdEditField.Value;
            write(app.ComPort, 255, 'uint8')
            write(app.ComPort, 1, 'uint8');
            write(app.ComPort, uint8(newvalue), 'uint8');
            read(app.ComPort, 5, 'uint8');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeamVisualization = uifigure('Visible', 'off');
            app.BeamVisualization.Name = 'BPM Visualizer';
            app.BeamVisualization.CloseRequestFcn = createCallbackFcn(app, @BeamVisualizationCloseRequest, true);
            app.TabGroup = uitabgroup(app.BeamVisualization);
            app.CrossectionalbeamvisualizationTab = uitab(app.TabGroup);
            app.CrossectionalbeamvisualizationTab.Title = 'Crossectional beam visualization';
            app.GridLayout3 = uigridlayout(app.CrossectionalbeamvisualizationTab);
            app.Beamparameterinfoconsole = uitextarea(app.GridLayout3);
            app.Beamparameterinfoconsole.Value = {'Beam parameter console'};
            app.CollectorAxes = uiaxes(app.GridLayout3);
            title(app.CollectorAxes, 'Collector Signal Cycle')
            xlabel(app.CollectorAxes, 'Time')
            ylabel(app.CollectorAxes, 'Amplitude')
            app.YpeakAxes = uiaxes(app.GridLayout3);
            title(app.YpeakAxes, 'Y peak')
            xlabel(app.YpeakAxes, 'X')
            ylabel(app.YpeakAxes, '')
            app.XpeakAxes = uiaxes(app.GridLayout3);
            title(app.XpeakAxes, 'X peak')
            xlabel(app.XpeakAxes, 'X')
            ylabel(app.XpeakAxes, '')
            app.SurfaceAxes = uiaxes(app.GridLayout3);
            title(app.SurfaceAxes, 'Transverse plane surface')
            xlabel(app.SurfaceAxes, 'X')
            ylabel(app.SurfaceAxes, '')
            app.ContourAxes = uiaxes(app.GridLayout3);
            title(app.ContourAxes, 'Transverse plane contour')
            xlabel(app.ContourAxes, 'X')
            ylabel(app.ContourAxes, '')
            app.UserpanelPanel = uipanel(app.GridLayout3);
            app.UserpanelPanel.Title = 'User panel';
            app.GridLayout4 = uigridlayout(app.UserpanelPanel);
            app.RS232Panel = uipanel(app.GridLayout4);
            app.RS232Panel.Title = 'RS232';
            app.StartCOMButton = uibutton(app.RS232Panel, 'push');
            app.StartCOMButton.ButtonPushedFcn = createCallbackFcn(app, @StartCOMButtonPushed, true);
            app.StartCOMButton.Text = 'Start COM';
            app.StartstopparamButton = uibutton(app.RS232Panel, 'push');
            app.StartstopparamButton.ButtonPushedFcn = createCallbackFcn(app, @StartstopparamButtonPushed, true);
            app.StartstopparamButton.Text = 'Start / stop param';
            app.UpdateplotButton = uibutton(app.RS232Panel, 'push');
            app.UpdateplotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButtonPushed, true);
            app.UpdateplotButton.Text = {'Update plot'; ''};
            app.ComportNr = uieditfield(app.RS232Panel, 'numeric');
            app.ComportNr.Value = 5;
            app.HTTPPanel = uipanel(app.GridLayout4);
            app.HTTPPanel.Title = 'HTTP';
            app.StartHTTPButton = uibutton(app.HTTPPanel, 'push');
            app.StartHTTPButton.ButtonPushedFcn = createCallbackFcn(app, @HTTPstart, true);
            app.StartHTTPButton.Text = 'Start HTTP';
            app.StartstopparamButtonHTTP = uibutton(app.HTTPPanel, 'push');
            app.StartstopparamButtonHTTP.ButtonPushedFcn = createCallbackFcn(app, @HTTPStartStop, true);
            app.StartstopparamButtonHTTP.Text = 'Start / stop param';
            app.UpdateplotButtonHTTP = uibutton(app.HTTPPanel, 'push');
            app.UpdateplotButtonHTTP.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotHTTPButtonPushed, true);
            app.UpdateplotButtonHTTP.Text = {'Update plot'; ''};
            app.HTTPportNr = uieditfield(app.HTTPPanel, 'numeric');
            app.HTTPportNr.Value = 80;
            app.TriggerdelayEditFieldLabel = uilabel(app.GridLayout4);
            app.TriggerdelayEditFieldLabel.Text = 'Trigger delay';
            app.TriggerdelayEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TriggerdelayEditField.ValueChangedFcn = createCallbackFcn(app, @AdjustTriggerDelay, true);
            app.BeamthresholdEditFieldLabel = uilabel(app.GridLayout4);
            app.BeamthresholdEditFieldLabel.Text = 'Beam threshold';
            app.BeamthresholdEditField = uieditfield(app.GridLayout4, 'numeric');
            app.BeamthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @adjustbeamThreshold, true);
            app.BeamthresholdEditField.Value = 20;
            app.convfactorILabel = uilabel(app.GridLayout4);
            app.convfactorILabel.Text = 'conv. factor I   ';
            app.convfactorIEditField = uieditfield(app.GridLayout4, 'numeric');
            app.convfactorIEditField.Value = 7020;
            app.DCcompensationCheckBox = uicheckbox(app.GridLayout4);
            app.DCcompensationCheckBox.ValueChangedFcn = createCallbackFcn(app, @dc_offset_compensation, true);
            app.DCcompensationCheckBox.Text = 'DC compensation';
            app.TemporalevolutionTab = uitab(app.TabGroup);
            app.TemporalevolutionTab.Title = 'Temporal evolution';
            app.GridLayout2 = uigridlayout(app.TemporalevolutionTab);
            app.BeamPositionAxes = uiaxes(app.GridLayout2);
            title(app.BeamPositionAxes, 'Beam position')
            xlabel(app.BeamPositionAxes, 'time index')
            ylabel(app.BeamPositionAxes, 'value')
            app.BeamIntensityAxes = uiaxes(app.GridLayout2);
            title(app.BeamIntensityAxes, 'Beam intensity')
            xlabel(app.BeamIntensityAxes, 'time index')
            ylabel(app.BeamIntensityAxes, 'value')
            app.BeamFWHMAxes = uiaxes(app.GridLayout2);
            title(app.BeamFWHMAxes, 'Beam FWHM')
            xlabel(app.BeamFWHMAxes, 'time index')
            ylabel(app.BeamFWHMAxes, 'value')
            app.BeamSkewnessAxes = uiaxes(app.GridLayout2);
            title(app.BeamSkewnessAxes, 'Beam skewness')
            xlabel(app.BeamSkewnessAxes, 'time index')
            ylabel(app.BeamSkewnessAxes, 'value')
            app.StartStopButton = uibutton(app.GridLayout2, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start/Stop';
            % Show the figure after all components are created
            app.BeamVisualization.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactiveAppMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeamVisualization)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeamVisualization)
        end
    end
end